classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SaveAsMenu                  matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        AdditiveNoiseTab            matlab.ui.container.Tab
        MultiplicativeNoiseTab      matlab.ui.container.Tab
        SaltPepperNoiseTab          matlab.ui.container.Tab
        GoButton                    matlab.ui.control.Button
        StartWindowSizeSliderLabel  matlab.ui.control.Label
        StartWindowSizeSlider       matlab.ui.control.Slider
        MaxWindowSizeSliderLabel    matlab.ui.control.Label
        MaxWindowSizeSlider         matlab.ui.control.Slider
        UIAxes                      matlab.ui.control.UIAxes
        SpectrumButton              matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        Label                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        img_reg % Description
%         img_processing
    end
    

    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % initialize
            addpath(genpath('.'));
%             try
%                 app.img_processing = ImageClass('res/processing.png');
%             catch ME
%                 fprintf('%s', ME.identifier);
%             end
            [f_name, f_path] = uigetfile('*.*', 'Select file');
            img_path = strcat(f_path, f_name);
            app.img_reg = ImageRegister(img_path);
            imshow(app.img_reg.getimg().getimg_uint8(), 'Parent', app.UIAxes);
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
            sp = app.img_reg.getimg().getspectrum();
            display_spctrm(sp);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.img_reg.reset();
            imshow(app.img_reg.getimg().getimg_uint8(), 'Parent', app.UIAxes);
        end

        % Value changed function: StartWindowSizeSlider
        function StartWindowSizeSliderValueChanged(app, event)
            value = round(app.StartWindowSizeSlider.Value);
            sz = app.img_reg.getimg().getsize();
            if mod(value, 2) == 0
                value = value - 1;
            end
            if value > min(sz(1:2))
                value = min(sz(1:2));
            end
            app.StartWindowSizeSlider.Value = value;
            app.MaxWindowSizeSlider.Value = value;
        end

        % Value changed function: MaxWindowSizeSlider
        function MaxWindowSizeSliderValueChanged(app, event)
            value = round(app.MaxWindowSizeSlider.Value);
            sz = app.img_reg.getimg().getsize();
            if mod(value, 2) == 0
                value = value - 1;
            end
            if value > min(sz(1:2))
                value = min(sz(1:2));
            end
            if value < app.StartWindowSizeSlider.Value
                value = app.StartWindowSizeSlider.Value;
            end
            app.MaxWindowSizeSlider.Value = value;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.Label.Text = 'Processing ...';
            pause(0.1);
%             imshow(app.img_processing.getimg_uint8(), 'Parent', app.UIAxes);
            img0 = app.img_reg.getimg().getimg_uint8();
            v_lo = app.StartWindowSizeSlider.Value;
            v_hi = app.MaxWindowSizeSlider.Value;
            img1 = adaptive_median_3d(img0, [v_lo, v_lo], [v_hi, v_hi]);
            app.img_reg.newimg(img1);
            imshow(img1, 'Parent', app.UIAxes);
            app.Label.Text = '';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AdditiveNoiseTab = uitab(app.TabGroup);
            app.AdditiveNoiseTab.Title = 'Additive Noise';
            app.MultiplicativeNoiseTab = uitab(app.TabGroup);
            app.MultiplicativeNoiseTab.Title = 'Multiplicative Noise';
            app.SaltPepperNoiseTab = uitab(app.TabGroup);
            app.SaltPepperNoiseTab.Title = 'Salt&Pepper Noise';
            app.GoButton = uibutton(app.SaltPepperNoiseTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.StartWindowSizeSliderLabel = uilabel(app.SaltPepperNoiseTab);
            app.StartWindowSizeSliderLabel.Text = 'Start Window Size';
            app.StartWindowSizeSlider = uislider(app.SaltPepperNoiseTab);
            app.StartWindowSizeSlider.Limits = [0 25];
            app.StartWindowSizeSlider.MajorTicks = [3 5 7 9 11 13 15 17 19 21 23 25];
            app.StartWindowSizeSlider.MajorTickLabels = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23', '25', ''};
            app.StartWindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @StartWindowSizeSliderValueChanged, true);
            app.StartWindowSizeSlider.MinorTicks = [];
            app.StartWindowSizeSlider.Value = 3;
            app.MaxWindowSizeSliderLabel = uilabel(app.SaltPepperNoiseTab);
            app.MaxWindowSizeSliderLabel.Text = 'Max Window Size';
            app.MaxWindowSizeSlider = uislider(app.SaltPepperNoiseTab);
            app.MaxWindowSizeSlider.Limits = [0 25];
            app.MaxWindowSizeSlider.MajorTicks = [3 5 7 9 11 13 15 17 19 21 23 25];
            app.MaxWindowSizeSlider.MajorTickLabels = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23', '25', ''};
            app.MaxWindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MaxWindowSizeSliderValueChanged, true);
            app.MaxWindowSizeSlider.MinorTicks = [];
            app.MaxWindowSizeSlider.Value = 3;
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SpectrumButton = uibutton(app.UIFigure, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Text = 'Spectrum';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end